classdef Markovian_Queueing_Models < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel  matlab.ui.container.Panel
        Label_3                       matlab.ui.control.Label
        CarlenombredtatsestfiniLabel  matlab.ui.control.Label
        SaisirlesparamtresdelafiledattenteLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        CalculdesprobabilitsstationnairesLabel  matlab.ui.control.Label
        CalculdescaractristiquesdeperformanceLabel  matlab.ui.control.Label
        ChoisirmodledefiledattenteDropDownLabel  matlab.ui.control.Label
        ChoisirmodledefiledattenteDropDown  matlab.ui.control.DropDown
        Label                         matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        RgimestationnaireEditFieldLabel  matlab.ui.control.Label
        RgimestationnaireEditField    matlab.ui.control.EditField
        P0EditFieldLabel              matlab.ui.control.Label
        P0EditField                   matlab.ui.control.NumericEditField
        PnEditFieldLabel              matlab.ui.control.Label
        PnEditField                   matlab.ui.control.NumericEditField
        nsEditFieldLabel              matlab.ui.control.Label
        nsEditField                   matlab.ui.control.NumericEditField
        tsEditFieldLabel              matlab.ui.control.Label
        tsEditField                   matlab.ui.control.NumericEditField
        nfEditFieldLabel              matlab.ui.control.Label
        nfEditField                   matlab.ui.control.NumericEditField
        tfEditFieldLabel              matlab.ui.control.Label
        tfEditField                   matlab.ui.control.NumericEditField
        SSpinnerLabel                 matlab.ui.control.Label
        SSpinner                      matlab.ui.control.Spinner
        SaisirnSpinnerLabel           matlab.ui.control.Label
        SaisirnSpinner                matlab.ui.control.Spinner
        SaisirLSpinnerLabel           matlab.ui.control.Label
        SaisirLSpinner                matlab.ui.control.Spinner
        eEditFieldLabel               matlab.ui.control.Label
        eEditField                    matlab.ui.control.NumericEditField
        PLEditFieldLabel              matlab.ui.control.Label
        PLEditField                   matlab.ui.control.NumericEditField
        TPFilesdattenteetSimulationKEBIRSaraSIQ4Label  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SSpinner.Value =1;
            app.SaisirLSpinner.Value=1;
             app.SaisirnSpinner.Value=1;
            app.SSpinner.Editable ='on';
            app.SaisirLSpinner.Editable='on';
            app.SSpinner.Visible ='off';
            app.SSpinnerLabel.Visible='off';
            app.RgimestationnaireEditField.Value='Non vérifié';
            app.Label_3.Visible='off';
            app.CalculdesprobabilitsstationnairesLabel.Visible='off';
            app.CalculdescaractristiquesdeperformanceLabel.Visible='off';
            app.P0EditField.Visible='off';
            app.P0EditFieldLabel.Visible='off';
            app.SaisirnSpinner.Visible='off';
            app.SaisirnSpinnerLabel.Visible='off';
            app.PnEditField.Visible='off';
            app.PnEditFieldLabel.Visible='off';
            app.nsEditField.Visible = 'off';
            app.nsEditFieldLabel.Visible = 'off';
            app.tsEditField.Visible = 'off';
            app.tsEditFieldLabel.Visible = 'off';
            app.nfEditField.Visible = 'off';
            app.nfEditFieldLabel.Visible = 'off';
            app.tfEditField.Visible = 'off';
            app.tfEditFieldLabel.Visible = 'off';
            app.CarlenombredtatsestfiniLabel.Visible='off';
            app.SaisirLSpinner.Visible='off';
            app.SaisirLSpinnerLabel.Visible='off';
            app.eEditField.Visible='off';
            app.eEditFieldLabel.Visible='off';
            app.PLEditField.Visible='off';
            app.PLEditFieldLabel.Visible='off';
        end

        % Callback function
        function ChoisirmodledefiledattenteDropDownValueChanged(app, event)
            value = app.ChoisirmodledefiledattenteDropDown.Value;
            startupFcn(app);
            if (string(value) == 'M/M/S' | string(value) == 'M/M/S/L'|string(value) == 'M/M/S/S'| string(value) == 'M/M/Infini')
                app.SSpinner.Visible ='on';
                app.SSpinnerLabel.Visible='on';
                if (string(value) == 'M/M/Infini')
                 app.CarlenombredtatsestfiniLabel.Visible='on';
                 app.CarlenombredtatsestfiniLabel.Text='Le régime stationnaire existe toujours!';
                 
                 app.SSpinner.Editable='off';
                 app.SSpinner.Value=Inf;
                end
            end
             if (string(value) == 'M/M/1' | string(value) == 'M/M/1/L')
                app.SSpinner.Value=1;
                app.SSpinner.Visible ='off';
                app.SSpinnerLabel.Visible='off';
             end
             if(string(value) == 'M/M/1/L'| string(value) == 'M/M/S/L'| string(value) == 'M/M/S/S')
             app.CarlenombredtatsestfiniLabel.Visible='on';
             app.SaisirLSpinner.Visible='on';
             app.SaisirLSpinnerLabel.Visible='on';
             if(string(value) == 'M/M/S/S')
                 app.SaisirLSpinner.Editable='off';
                 app.SaisirLSpinner.Value=app.SSpinner.Value;
             end 
             end
             
             EditField_3ValueChanged(app, event);
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            EditField_3ValueChanged(app, event); 
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            EditField_3ValueChanged(app, event);
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
           app.EditField_3.Value= app.EditField.Value/(app.SSpinner.Value * app.EditField_2.Value);
           modeleChoix=string(app.ChoisirmodledefiledattenteDropDown.Value);
            if(modeleChoix == 'M/M/1' | modeleChoix == 'M/M/S')
                if(app.EditField_3.Value <1)
                     app.RgimestationnaireEditField.Value = 'Vérifié';
                     RgimestationnaireEditFieldValueChanged(app,event);
                     P0EditFieldValueChanged(app, event);
                     PnEditFieldValueChanged(app, event);
                     else
                     app.RgimestationnaireEditField.Value = 'Non Vérifié';
                     RgimestationnaireEditFieldValueChanged(app,event);
                end
            end
            
            if(modeleChoix == 'M/M/1/L' |modeleChoix == 'M/M/S/L'|modeleChoix == 'M/M/S/S')
                    app.RgimestationnaireEditField.Value = 'Vérifié';
                    RgimestationnaireEditFieldValueChanged(app,event);
                    app.SaisirLSpinner.Visible='on';
                    app.SaisirLSpinnerLabel.Visible='on';
                    app.PLEditField.Visible='on';
                    app.PLEditFieldLabel.Visible='on';
                    P0EditFieldValueChanged(app, event);
                    PnEditFieldValueChanged(app, event);
                    PLEditFieldValueChanged(app,event);
            end
             if(modeleChoix == 'M/M/Infini')
             app.RgimestationnaireEditField.Value = 'Vérifié';
             RgimestationnaireEditFieldValueChanged(app,event);
             P0EditFieldValueChanged(app, event);
             PnEditFieldValueChanged(app, event);
             end 
            eEditFieldValueChanged(app, event);
            nsEditFieldValueChanged(app, event);
            nfEditFieldValueChanged(app, event);
           
            
        end

        % Callback function
        function P0EditFieldValueChanged(app, event)
               syms k;
               U= app.EditField.Value / app.EditField_2.Value;
               if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/1')
               app.P0EditField.Value = 1 - app.EditField_3.Value;
               end
               if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/1/L')
                 if(app.EditField_3.Value==1)
                   app.P0EditField.Value = 1 / ( app.SaisirLSpinner.Value + 1 );
                 else
                   app.P0EditField.Value = (1 -app.EditField_3.Value)/ (1 - (app.EditField_3.Value .^ ( app.SaisirLSpinner.Value + 1 )));
                 end
                end
                if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/S')
                app.P0EditField.Value = double((symsum(((U) ^ k) / factorial(k),k,0, app.SSpinner.Value - 1) + (app.SSpinner.Value * (U .^ app.SSpinner.Value)) / (factorial(app.SSpinner.Value) * (app.SSpinner.Value - U))) .^ -1);
                end 
                if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/S/L')
                
                    if(U ~= app.SSpinner.Value)
                        app.P0EditField.Value = double((symsum(((U) ^ k) / factorial(k),k,0, app.SSpinner.Value - 1) + (((U) ^ app.SSpinner.Value) / factorial(app.SSpinner.Value)) * (((1 - ((U / app.SSpinner.Value)^(app.SaisirLSpinner.Value -app.SSpinner.Value+1))) / (1-((U / app.SSpinner.Value)))))) ^ -1);
                    else
                        app.P0EditField.Value = double((symsum(((app.SSpinner.Value) ^ k) / factorial(k),k,0, app.SSpinner.Value - 1) + (((app.SSpinner.Value) ^ app.SSpinner.Value) / factorial(app.SSpinner.Value)) * (app.SaisirLSpinner.Value-app.SSpinner.Value+1)) ^ -1);
                    end
                end
                 if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/S/S')
                    app.P0EditField.Value = double((symsum(((U) ^ k) / factorial(k),k,0, app.SSpinner.Value))^-1);
            
                 end 
                   if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/Infini')
                       app.P0EditField.Value = exp(-U);
                   end
        end

        % Callback function
        function PLEditFieldValueChanged(app, event)
            U= app.EditField.Value / app.EditField_2.Value;
            if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/1/L')
                if ( app.EditField_3.Value==1)
                app.PLEditField.Value = (1 / (app.SaisirLSpinner.Value +1));
                else
                app.PLEditField.Value= ((1 - app.EditField_3.Value)* (app.EditField_3.Value .^ app.SaisirLSpinner.Value ))/(1 - (app.EditField_3.Value .^ ( app.SaisirLSpinner.Value + 1 )));
                end
            end
            if(string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/S/L' | string(app.ChoisirmodledefiledattenteDropDown.Value) == 'M/M/S/S')
                app.PLEditField.Value = ((U ^ app.SaisirLSpinner.Value) / (factorial(app.SSpinner.Value) * (app.SSpinner.Value ^ (app.SaisirLSpinner.Value - app.SSpinner.Value)))) * app.P0EditField.Value;
            end 
        end

        % Callback function
        function PnEditFieldValueChanged(app, event)
            U= app.EditField.Value / app.EditField_2.Value;
            value = string(app.ChoisirmodledefiledattenteDropDown.Value);
            if(value == 'M/M/1')
                app.PnEditField.Value = app.P0EditField.Value * (app.EditField_3.Value .^ app.SaisirnSpinner.Value);
            end
            if(value == 'M/M/1/L')
                if ( app.EditField_3.Value==1)
                app.PnEditField.Value = 1 / (app.SaisirLSpinner.Value +1);
                else
                app.PnEditField.Value= ((1 - app.EditField_3.Value)* (app.EditField_3.Value .^ app.SaisirnSpinner.Value ))/(1 - (app.EditField_3.Value .^ ( app.SaisirLSpinner.Value + 1 )));
                end
            end
            if(value == 'M/M/S')
                if (app.SaisirnSpinner.Value <= app.SSpinner.Value)
                   app.PnEditField.Value = ((U ^ app.SaisirnSpinner.Value) / (factorial(app.SaisirnSpinner.Value))) * app.P0EditField.Value;
                else
                   app.PnEditField.Value = ((U ^ app.SaisirnSpinner.Value) / (factorial(app.SSpinner.Value) * (app.SSpinner.Value ^ (app.SaisirnSpinner.Value - app.SSpinner.Value)))) * app.P0EditField.Value; 
                end
            end
            if(value == 'M/M/S/L')
                if(app.SaisirnSpinner.Value <= app.SSpinner.Value)
                        app.PnEditField.Value = ((U ^ app.SaisirnSpinner.Value) / (factorial(app.SaisirnSpinner.Value))) * app.P0EditField.Value;
                    else
                        app.PnEditField.Value = ((U ^ app.SaisirnSpinner.Value) / (factorial(app.SSpinner.Value) * (app.SSpinner.Value ^ (app.SaisirnSpinner.Value - app.SSpinner.Value)))) * app.P0EditField.Value;
                    end
            end 
             if(value == 'M/M/S/S')
            if (app.SaisirnSpinner.Value <= app.SSpinner.Value)
                        app.PnEditField.Value = (((U) ^ app.SaisirnSpinner.Value) / factorial(app.SaisirnSpinner.Value)) * app.P0EditField.Value;
                    else
                        app.PnEditField.Value = 0;
            end 
             end 
             if (value == 'M/M/Infini')
              app.PnEditField.Value = (((U) ^ app.SaisirnSpinner.Value) / factorial(app.SaisirnSpinner.Value)) * app.P0EditField.Value;
            
             end
             
        end

        % Callback function
        function RgimestationnaireEditFieldValueChanged(app, event)
            value = app.RgimestationnaireEditField.Value;
            if(string(value)=='Vérifié')
                app.Label_3.Visible='on';
                app.CalculdesprobabilitsstationnairesLabel.Visible='on';
                app.CalculdescaractristiquesdeperformanceLabel.Visible='on';
                app.P0EditField.Visible='on';
                app.P0EditFieldLabel.Visible='on';
                app.SaisirnSpinner.Visible='on';
                app.SaisirnSpinnerLabel.Visible='on';
                app.PnEditField.Visible='on';
                app.PnEditFieldLabel.Visible='on';
                app.nsEditField.Visible = 'on';
                app.nsEditFieldLabel.Visible = 'on';
                app.tsEditField.Visible = 'on';
                app.tsEditFieldLabel.Visible = 'on';
                app.nfEditField.Visible = 'on';
                app.nfEditFieldLabel.Visible = 'on';
                app.tfEditField.Visible = 'on';
                app.tfEditFieldLabel.Visible = 'on';
                app.eEditField.Visible='on';
                app.eEditFieldLabel.Visible='on';
            else
                app.Label_3.Visible='off';
                app.CalculdesprobabilitsstationnairesLabel.Visible='off';
                app.CalculdescaractristiquesdeperformanceLabel.Visible='off';
                app.P0EditField.Visible='off';
                app.P0EditFieldLabel.Visible='off';
                app.SaisirnSpinner.Visible='off';
                app.SaisirnSpinnerLabel.Visible='off';
                app.PnEditField.Visible='off';
                app.PnEditFieldLabel.Visible='off';
                app.nsEditField.Visible = 'off';
                app.nsEditFieldLabel.Visible = 'off';
                app.tsEditField.Visible = 'off';
                app.tsEditFieldLabel.Visible = 'off';
                app.nfEditField.Visible = 'off';
                app.nfEditFieldLabel.Visible = 'off';
                app.tfEditField.Visible = 'off';
                app.tfEditFieldLabel.Visible = 'off';
                app.SaisirLSpinner.Visible='off';
                app.SaisirLSpinnerLabel.Visible='off';
                app.eEditField.Visible='off';
                app.eEditFieldLabel.Visible='off';
                app.PLEditField.Visible='off';
                app.PLEditFieldLabel.Visible='off';
                
            end
        end

        % Callback function
        function SSpinnerValueChanged(app, event)
           if( string(app.ChoisirmodledefiledattenteDropDown.Value)=='M/M/S/S')
               app.SaisirLSpinner.Value=app.SSpinner.Value;
           end 
           if( string(app.ChoisirmodledefiledattenteDropDown.Value)=='M/M/S/L')
               if (app.SSpinner.Value > app.SaisirLSpinner.Value)
                   app.SSpinner.Value=app.SaisirLSpinner.Value;
               end 
           end 
            value = app.SSpinner.Value;
            EditField_3ValueChanged(app,event);
            
        end

        % Callback function
        function SaisirLSpinnerValueChanged(app, event)
           if( string(app.ChoisirmodledefiledattenteDropDown.Value)=='M/M/S/S')
               app.SaisirLSpinner.Value=app.SSpinner.Value;
           end 
           if( string(app.ChoisirmodledefiledattenteDropDown.Value)=='M/M/S/L')
               if (app.SSpinner.Value > app.SaisirLSpinner.Value)
                   app.SaisirLSpinner.Value=app.SSpinner.Value;
               end
           end 
            value = app.SaisirLSpinner.Value;
            P0EditFieldValueChanged(app, event);
            PnEditFieldValueChanged(app, event);
            PLEditFieldValueChanged(app, event);
            eEditFieldValueChanged(app, event);
            nsEditFieldValueChanged(app, event);
            nfEditFieldValueChanged(app, event);
        end

        % Callback function
        function SaisirnSpinnerValueChanged(app, event)
             value = string(app.ChoisirmodledefiledattenteDropDown.Value);
            if(value == 'M/M/1/L' | value == 'M/M/S/L' )
            if (app.SaisirnSpinner.Value > app.SaisirLSpinner.Value)
               app.SaisirnSpinner.Value = app.SaisirLSpinner.Value;
            end 
            end 
            PnEditFieldValueChanged(app, event); 
            
        end

        % Callback function
        function eEditFieldValueChanged(app, event)
            value = string(app.ChoisirmodledefiledattenteDropDown.Value);
            if(value == 'M/M/1' | value == 'M/M/S' | value == 'M/M/Infini' )
             app.eEditField.Value= app.EditField.Value;
            end
            if(value == 'M/M/1/L' | value == 'M/M/S/L' | value == 'M/M/S/S')
             app.eEditField.Value= app.EditField.Value * (1 - app.PLEditField.Value);
            end
            
        end

        % Callback function
        function nfEditFieldValueChanged(app, event)
            app.nfEditField.Value= app.nsEditField.Value - (app.eEditField.Value / app.EditField_2.Value)
           
             if(app.nfEditField.Value < 0)
                app.nfEditField.Value=0;
             end 
            tfEditFieldValueChanged(app, event);
            
        end

        % Callback function
        function nsEditFieldValueChanged(app, event)
            value =string(app.ChoisirmodledefiledattenteDropDown.Value);
            if(value == 'M/M/1')
            app.nsEditField.Value = app.EditField_3.Value / ( 1 - app.EditField_3.Value);
            end
             if(value == 'M/M/1/L')
                 if(app.EditField_3.Value == 1 )
                    app.nsEditField.Value =  app.SaisirLSpinner.Value / 2;
                 else 
                    app.nsEditField.Value = ( app.EditField_3.Value / (1 - app.EditField_3.Value)) - (((app.SaisirLSpinner.Value + 1)* (app.EditField_3.Value .^ (app.SaisirLSpinner.Value + 1)))/ (1 - (app.EditField_3.Value .^ (app.SaisirLSpinner.Value + 1))))
                 end 
             end
              if(app.nsEditField.Value < 0)
                app.nsEditField.Value=0;
              end
              if(value == 'M/M/S')
                   U= app.EditField.Value / app.EditField_2.Value;
                   Ps = ((U ^ app.SSpinner.Value) / (factorial(app.SSpinner.Value))) * app.P0EditField.Value;
                    if (app.EditField_3.Value < 1)
                    app.nsEditField.Value = (app.EditField_3.Value / ((1 - app.EditField_3.Value) ^ 2)) * Ps + app.SSpinner.Value * app.EditField_3.Value;
                    end
              end
              if(value == 'M/M/S/L' | value == 'M/M/S/S')
                  if (app.EditField_3.Value ~= 1)
                       app.nsEditField.Value = app.P0EditField.Value * ((((app.SSpinner.Value * app.EditField_3.Value)^app.SSpinner.Value)*app.EditField_3.Value) / (factorial(app.SSpinner.Value)*((1-app.EditField_3.Value)^2))) * (1-(app.EditField_3.Value ^ (app.SaisirLSpinner.Value-app.SSpinner.Value+1)) - (1-app.EditField_3.Value)*(app.SaisirLSpinner.Value-app.SSpinner.Value+1) * (app.EditField_3.Value ^ (app.SaisirLSpinner.Value-app.SSpinner.Value))) + (app.eEditField.Value / app.EditField_2.Value);
                    else
                       app.nsEditField.Value = app.P0EditField.Value * ((app.SSpinner.Value ^ app.SSpinner.Value)/(factorial(app.SSpinner.Value))) * (((app.SaisirLSpinner.Value-app.SSpinner.Value)*(app.SaisirLSpinner.Value-app.SSpinner.Value+1))/2) + (app.eEditField.Value / app.EditField_2.Value);
                    end 
              end
              if (value == 'M/M/Infini')
                  app.nsEditField.Value = app.EditField.Value / app.EditField_2.Value;
              end 
                    tsEditFieldValueChanged(app, event);            
        end

        % Callback function
        function tfEditFieldValueChanged(app, event)
            
            app.tfEditField.Value= app.nfEditField.Value/app.eEditField.Value;
             if(app.tfEditField.Value < 0)
                app.tfEditField.Value=0;
             end
            
        end

        % Callback function
        function tsEditFieldValueChanged(app, event)
            
            app.tsEditField.Value=app.nsEditField.Value/app.eEditField.Value;  
            if(app.tsEditField.Value < 0)
                app.tsEditField.Value=0;
            end 
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel = uipanel(app.UIFigure);
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel.Title = 'TP Files d''attente et Simulation -KEBIR Sara SIQ 4-';
            app.Label_3 = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.Label_3.Text = '_____________________________________________________________________________________________________';
            app.CarlenombredtatsestfiniLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.CarlenombredtatsestfiniLabel.Text = 'Car le nombre d''états est fini ! ';
            app.SaisirlesparamtresdelafiledattenteLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SaisirlesparamtresdelafiledattenteLabel.Text = 'Saisir les paramètres de la file d''attente : ';
            app.Label_2 = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.Label_2.Text = '==>';
            app.CalculdesprobabilitsstationnairesLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.CalculdesprobabilitsstationnairesLabel.Text = 'Calcul des probabilités stationnaires :';
            app.CalculdescaractristiquesdeperformanceLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.CalculdescaractristiquesdeperformanceLabel.Text = 'Calcul des caractéristiques de performance  :';
            app.ChoisirmodledefiledattenteDropDownLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.ChoisirmodledefiledattenteDropDownLabel.Text = 'Choisir modèle de file d''attente';
            app.ChoisirmodledefiledattenteDropDown = uidropdown(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.ChoisirmodledefiledattenteDropDown.Items = {'M/M/1', 'M/M/1/L', 'M/M/S', 'M/M/S/L', 'M/M/S/S', 'M/M/Infini'};
            app.ChoisirmodledefiledattenteDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoisirmodledefiledattenteDropDownValueChanged, true);
            app.ChoisirmodledefiledattenteDropDown.Value = 'M/M/1';
            app.Label = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.Label.Text = '𝛌';
            app.EditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.EditField.LowerLimitInclusive = 'off';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 1;
            app.EditField_2Label = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.EditField_2Label.Text = 'μ';
            app.EditField_2 = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.EditField_2.LowerLimitInclusive = 'off';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Value = 1;
            app.EditField_3Label = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.EditField_3Label.Text = 'ρ';
            app.EditField_3 = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 1;
            app.RgimestationnaireEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.RgimestationnaireEditFieldLabel.Text = 'Régime stationnaire ';
            app.RgimestationnaireEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'text');
            app.RgimestationnaireEditField.ValueChangedFcn = createCallbackFcn(app, @RgimestationnaireEditFieldValueChanged, true);
            app.RgimestationnaireEditField.Editable = 'off';
            app.RgimestationnaireEditField.Value = 'Vérifié';
            app.P0EditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.P0EditFieldLabel.Text = 'P0';
            app.P0EditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.P0EditField.ValueChangedFcn = createCallbackFcn(app, @P0EditFieldValueChanged, true);
            app.P0EditField.Editable = 'off';
            app.PnEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.PnEditFieldLabel.Text = 'Pn';
            app.PnEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.PnEditField.ValueChangedFcn = createCallbackFcn(app, @PnEditFieldValueChanged, true);
            app.PnEditField.Editable = 'off';
            app.nsEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.nsEditFieldLabel.Text = 'ns';
            app.nsEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.nsEditField.ValueChangedFcn = createCallbackFcn(app, @nsEditFieldValueChanged, true);
            app.nsEditField.Editable = 'off';
            app.tsEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.tsEditFieldLabel.Text = 'ts';
            app.tsEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.tsEditField.ValueChangedFcn = createCallbackFcn(app, @tsEditFieldValueChanged, true);
            app.tsEditField.Editable = 'off';
            app.nfEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.nfEditFieldLabel.Text = 'nf';
            app.nfEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.nfEditField.ValueChangedFcn = createCallbackFcn(app, @nfEditFieldValueChanged, true);
            app.nfEditField.Editable = 'off';
            app.tfEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.tfEditField.ValueChangedFcn = createCallbackFcn(app, @tfEditFieldValueChanged, true);
            app.tfEditField.Editable = 'off';
            app.SSpinnerLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SSpinnerLabel.Text = 'S';
            app.SSpinner = uispinner(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SSpinner.ValueChangedFcn = createCallbackFcn(app, @SSpinnerValueChanged, true);
            app.SSpinner.Limits = [1 Inf];
            app.SSpinner.Value = 1;
            app.SaisirnSpinnerLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SaisirnSpinnerLabel.Text = 'Saisir n';
            app.SaisirnSpinner = uispinner(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SaisirnSpinner.ValueChangedFcn = createCallbackFcn(app, @SaisirnSpinnerValueChanged, true);
            app.SaisirnSpinner.Limits = [1 Inf];
            app.SaisirnSpinner.Value = 1;
            app.SaisirLSpinnerLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SaisirLSpinnerLabel.Text = 'Saisir L';
            app.SaisirLSpinner = uispinner(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.SaisirLSpinner.ValueChangedFcn = createCallbackFcn(app, @SaisirLSpinnerValueChanged, true);
            app.SaisirLSpinner.Limits = [1 Inf];
            app.SaisirLSpinner.Value = 1;
            app.eEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.eEditFieldLabel.Text = '𝛌e';
            app.eEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.eEditField.Limits = [1e-10 Inf];
            app.eEditField.Editable = 'off';
            app.eEditField.Value = 1;
            app.PLEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.PLEditFieldLabel.Text = 'PL';
            app.PLEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.PLEditField.ValueChangedFcn = createCallbackFcn(app, @PLEditFieldValueChanged, true);
            app.PLEditField.Editable = 'off';
            app.PLEditField.Value = 1;
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Label = uilabel(app.UIFigure);
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Label.Text = 'TP Files d''attente et Simulation -KEBIR Sara SIQ 4-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Markovian_Queueing_Models

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end